classdef TireAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TireAnalyzerUIFigure           matlab.ui.Figure
        TotalGridLayout                matlab.ui.container.GridLayout
        TopGridLayout                  matlab.ui.container.GridLayout
        StatusPanel                    matlab.ui.container.Panel
        TertiaryStatusLabel            matlab.ui.control.Label
        SecondaryStatusLabel           matlab.ui.control.Label
        PrimaryStatusLabel             matlab.ui.control.Label
        TitlePanel                     matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        Panel_23                       matlab.ui.container.Panel
        Hyperlink                      matlab.ui.control.Hyperlink
        PacejkaFitNotCompletedLabel    matlab.ui.control.Label
        SeesourcecodeLabel             matlab.ui.control.Label
        ByMatthewDrummondStoylesLabel  matlab.ui.control.Label
        TireAnalyzerLabel              matlab.ui.control.Label
        RFRImage                       matlab.ui.control.Image
        Panel_22                       matlab.ui.container.Panel
        LoadingImage                   matlab.ui.control.Image
        BottomGridLayout               matlab.ui.container.GridLayout
        TireInfoPanel                  matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        ErrorLabel                     matlab.ui.control.Label
        TestSectionTree                matlab.ui.container.Tree
        GridLayout15                   matlab.ui.container.GridLayout
        Label8                         matlab.ui.control.Label
        Label7                         matlab.ui.control.Label
        Label6                         matlab.ui.control.Label
        Label5                         matlab.ui.control.Label
        Label4                         matlab.ui.control.Label
        Label3                         matlab.ui.control.Label
        Label2                         matlab.ui.control.Label
        TTCRoundLabel                  matlab.ui.control.Label
        RunOptionsPanel                matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        GridLayout16                   matlab.ui.container.GridLayout
        RunButton                      matlab.ui.control.Button
        ScalingfactorsPanel            matlab.ui.container.Panel
        YscalingfactorEditField        matlab.ui.control.NumericEditField
        ForcecoefficientYscalingfactorEditFieldLabel  matlab.ui.control.Label
        XscalingfactorEditField        matlab.ui.control.NumericEditField
        ScalingfactorLabel             matlab.ui.control.Label
        UsedifferentXandYscalingfactorsCheckBox  matlab.ui.control.CheckBox
        FitOptionsPanel                matlab.ui.container.Panel
        SeparateSACheckBox             matlab.ui.control.CheckBox
        PacejkaalgorithmDropDown       matlab.ui.control.DropDown
        PacejkaalgorithmDropDownLabel  matlab.ui.control.Label
        PacejkafitmaxiterationsEditField  matlab.ui.control.NumericEditField
        PacejkafitmaxiterationsEditFieldLabel  matlab.ui.control.Label
        LoadUBEditField                matlab.ui.control.NumericEditField
        LoadupperboundNEditFieldLabel  matlab.ui.control.Label
        LoadStepSizeEditField          matlab.ui.control.NumericEditField
        LoadstepsizeNEditFieldLabel    matlab.ui.control.Label
        SAStepSizeEditField            matlab.ui.control.NumericEditField
        SlipanglestepsizedegEditFieldLabel  matlab.ui.control.Label
        SABoundEditField               matlab.ui.control.NumericEditField
        SlipanglebounddegLabel         matlab.ui.control.Label
        LoadSensitivityOrderSpinner    matlab.ui.control.Spinner
        LoadsensitivityorderLabel      matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        SelectindividualparametersButton  matlab.ui.control.RadioButton
        SelectfromdropdownButton       matlab.ui.control.RadioButton
        Panel_11                       matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        Panel_18                       matlab.ui.container.Panel
        RimwidthDropDown               matlab.ui.control.DropDown
        RimwidthDropDownLabel          matlab.ui.control.Label
        Panel_17                       matlab.ui.container.Panel
        CompoundDropDown               matlab.ui.control.DropDown
        CompoundDropDownLabel          matlab.ui.control.Label
        Panel_16                       matlab.ui.container.Panel
        RimouterdiameterDropDown       matlab.ui.control.DropDown
        RimouterdiameterDropDownLabel  matlab.ui.control.Label
        Panel_15                       matlab.ui.container.Panel
        TirewidthDropDown              matlab.ui.control.DropDown
        TirewidthDropDownLabel         matlab.ui.control.Label
        Panel_14                       matlab.ui.container.Panel
        OuterdiameterDropDown          matlab.ui.control.DropDown
        OuterdiameterDropDownLabel     matlab.ui.control.Label
        Panel_13                       matlab.ui.container.Panel
        ManufacturerDropDown           matlab.ui.control.DropDown
        ManufacturerDropDownLabel      matlab.ui.control.Label
        Panel_12                       matlab.ui.container.Panel
        RoundDropDown                  matlab.ui.control.DropDown
        RoundDropDownLabel             matlab.ui.control.Label
        RimWidthCheckBox               matlab.ui.control.CheckBox
        CompoundCheckBox               matlab.ui.control.CheckBox
        RimODCheckBox                  matlab.ui.control.CheckBox
        TireWidthCheckBox              matlab.ui.control.CheckBox
        ODCheckbox                     matlab.ui.control.CheckBox
        ManufacturerCheckBox           matlab.ui.control.CheckBox
        RoundCheckBox                  matlab.ui.control.CheckBox
        Panel_19                       matlab.ui.container.Panel
        TireLabel                      matlab.ui.control.Label
        TireDropDown                   matlab.ui.control.DropDown
        Panel_20                       matlab.ui.container.Panel
        SaveFyandMzdataCheckBox        matlab.ui.control.CheckBox
        Panel_21                       matlab.ui.container.Panel
        CreatefitsCheckBox             matlab.ui.control.CheckBox
        PlotsGridLayout                matlab.ui.container.GridLayout
        LoadSensitivityPanel           matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        FZClickRunLabel                matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        UBdegLabel                     matlab.ui.control.Label
        SASlider                       matlab.ui.control.Slider
        LBdegLabel                     matlab.ui.control.Label
        GridLayout2_2                  matlab.ui.container.GridLayout
        Panel_4                        matlab.ui.container.Panel
        FZSAdirDropDown                matlab.ui.control.DropDown
        SAdirDropDownLabel             matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        FZPDropDown                    matlab.ui.control.DropDown
        PpsiDropDown_2Label            matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        FZIADropDown                   matlab.ui.control.DropDown
        IAdegDropDown_2Label           matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        FZVaryDropDown                 matlab.ui.control.DropDown
        VaryDropDownLabel              matlab.ui.control.Label
        FZAxes                         matlab.ui.control.UIAxes
        PressureSensitivityPanel       matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        NotimplementedyetLabel_2       matlab.ui.control.Label
        CamberSensitivityPanel         matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        NotimplementedyetLabel         matlab.ui.control.Label
        PacejkaFitsPanel               matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        Panel_9                        matlab.ui.container.Panel
        PacejkaSAdirDropDownLabel      matlab.ui.control.Label
        PacejkaSAdirDropDown           matlab.ui.control.DropDown
        Panel_8                        matlab.ui.container.Panel
        PacejkaFZDropDownLabel         matlab.ui.control.Label
        PacejkaFZDropDown              matlab.ui.control.DropDown
        Panel_7                        matlab.ui.container.Panel
        PacejkaOnlyPDropDownLabel      matlab.ui.control.Label
        PacejkaOnlyPDropDown           matlab.ui.control.DropDown
        PacejkaBothPDropDownLabel      matlab.ui.control.Label
        PacejkaBothPDropDown           matlab.ui.control.DropDown
        Panel_6                        matlab.ui.container.Panel
        PacejkaOnlyIADropDownLabel     matlab.ui.control.Label
        PacejkaOnlyIADropDown          matlab.ui.control.DropDown
        PacejkaBothIADropDownLabel     matlab.ui.control.Label
        PacejkaBothIADropDown          matlab.ui.control.DropDown
        Panel_5                        matlab.ui.container.Panel
        PacejkaVaryDropDown            matlab.ui.control.DropDown
        PacejkaVaryDropDownLabel       matlab.ui.control.Label
        PacejkaClickRunLabel           matlab.ui.control.Label
        PacejkaFitAxes                 matlab.ui.control.UIAxes
    end

    
    % ----- INFO -----
    % 1. Indexing goes: (sa, sa_dir, load, camber, pressure)
    % 2. Naming convention: camelCase, I guess CamelCase for app properties
    % ----------------
    
    properties (Access = private)
        % Data files info
        nFiles % Number of data files
        nTires
        FileNames
        TireNamesRaw
        TireNames
        TireInfoRaw cell
        TireInfoMap containers.Map
        TireDataMap containers.Map % Tire data variables
        TireDataRaw cell
        TireNamesSorted cell % Tire names in order of which was found first
        UsedTireData struct % Ones actually used
        FileStartIndices
        PacejkaDataAxesHandle
        PacejkaFitAxesHandle
        LoadSensAxesDataHandle
        LoadSensAxesFitHandle
        CamberSensAxesHandle
        PressureSensAxesHandle
        Round
        RunOptions struct = struct( ...
            'SaveData', false, ...
            'UseDiffScalingFactors', false, ...
            'XScalingFactor', 1, ...
            'YScalingFactor', 1, ...
            'CreateFits', true, ...
            'SeparateSADir', false, ...
            'SABound', 15, ...
            'SAStepSize', 0.1, ...
            'SASampleVals', 0, ...
            'PacejkaMaxIterations', 3000, ...
            'PacejkaAlgorithm', 'levenberg-marquardt', ...
            'LoadStepSize', 20, ...
            'LoadUpperBound', 1200, ...
            'LoadSampleVals', 0, ...
            'LoadSensOrder', 2 ...
            )
        PacejkaState CamberOrPressureState
        LoadSensIAorPState CamberOrPressureState
        
        % Data processing global variables
        DataProcessingDepth = 0;
        DataProcessingCurrentNode
        TestSectionNames
    end
    
    properties (Access = public)
        LatPressureLUTData
        LatCamberLUTData
        LatLUTData
    end
    
    properties (Constant)
        TestSectionActions containers.Map = containers.Map( ...
            {'Inc angles SA sweeps', ...
            'Pressures SA sweeps', ...
            'Main SA sweeps'}, ...
            {SASweepsAction(), ...
            SASweepsAction(), ...
            SASweepsAction()});
    end
    
    methods (Access = private)
        
        function updateUI(~)
            % For some reason, this works
            pause(0.000001);
        end
        
        function updateTireDetails(app, tireID)
            roundNum = app.getRoundNumber(tireID);
            
            app.RoundDropDown.Value = num2str(roundNum);
            
            manu = extractBetween(tireID, '', ' ');
            
            app.ManufacturerDropDown.Value = manu{1};
        end
        
        function round = getRoundNumber(app, tireID)
            tireInfo = app.TireInfoMap(tireID);
            fileName = tireInfo.fileName;
            cellVal = extractBetween(fileName, 2, 5);
            num = cellVal{1};
            switch num
                case '1051'
                    round = 1;
                case '1052'
                    round = 2;
                case '1175'
                    round = 3;
                case '1320'
                    round = 4;
                case '1464'
                    round = 5;
                case '1654'
                    round = 6;
                case '1706'
                    round = 7;
                case '1965'
                    round = 8;
            end
        end
        
        function addTestSectionAction(app, testSectionName, callback)
            app.TestSectionActions(testSectionName) = callback;
        end
        
        function SASweepsAction(app, relevantData)
            
        end
        
        function switchPacejkaState(app, state)
            switch state
                case CamberOrPressureState.Camber
                    app.PacejkaBothIADropDown.Visible = false;
                    app.PacejkaBothIADropDownLabel.Visible = false;
                    app.PacejkaBothPDropDown.Visible = false;
                    app.PacejkaBothPDropDownLabel.Visible = false;
                    app.PacejkaVaryDropDown.Visible = true;
                    app.PacejkaVaryDropDownLabel.Visible = true;
                    app.PacejkaOnlyPDropDown.Visible = false;
                    app.PacejkaOnlyPDropDownLabel.Visible = false;
                    app.PacejkaOnlyIADropDown.Visible = true;
                    app.PacejkaOnlyIADropDownLabel.Visible = true;
                case CamberOrPressureState.Pressure
                    app.PacejkaBothIADropDown.Visible = false;
                    app.PacejkaBothIADropDownLabel.Visible = false;
                    app.PacejkaBothPDropDown.Visible = false;
                    app.PacejkaBothPDropDownLabel.Visible = false;
                    app.PacejkaVaryDropDown.Visible = true;
                    app.PacejkaVaryDropDownLabel.Visible = true;
                    app.PacejkaOnlyIADropDown.Visible = false;
                    app.PacejkaOnlyIADropDownLabel.Visible = false;
                    app.PacejkaOnlyPDropDown.Visible = true;
                    app.PacejkaOnlyPDropDownLabel.Visible = true;
                case CamberOrPressureState.Both
                    app.PacejkaVaryDropDown.Enable = 'off';
                    app.PacejkaVaryDropDownLabel.Enable = 'off';
                    app.PacejkaOnlyPDropDown.Visible = false;
                    app.PacejkaOnlyPDropDownLabel.Visible = false;
                    app.PacejkaOnlyIADropDown.Visible = false;
                    app.PacejkaOnlyIADropDownLabel.Visible = false;
                    app.PacejkaBothIADropDown.Visible = true;
                    app.PacejkaBothIADropDownLabel.Visible = true;
                    app.PacejkaBothPDropDown.Visible = true;
                    app.PacejkaBothPDropDownLabel.Visible = true;
            end
            app.PacejkaState = state;
        end
        
        function switchLoadSensState(app, state)
            switch state
                case CamberOrPressureState.Camber
                    app.FZPDropDown.Enable = 'off';
                    app.FZIADropDown.Enable = 'on';
                case CamberOrPressureState.Pressure
                    app.FZPDropDown.Enable = 'on';
                    app.FZIADropDown.Enable = 'off';
                case CamberOrPressureState.Both
                    app.FZVaryDropDown.Enable = 'off';
                    app.FZPDropDown.Enable = 'on';
                    app.FZIADropDown.Enable = 'on';
            end
            app.LoadSensIAorPState = state;
        end
        
        function updatePacejkaAxes(app)
            delete(app.PacejkaDataAxesHandle);
            delete(app.PacejkaFitAxesHandle);
            
            switch app.PacejkaState
                case CamberOrPressureState.Camber
                    fzIndex = getFZIndexFromStringPacejka(app, app.PacejkaFZDropDown.Value);
                    iaIndex = getIAIndexFromStringPacejka(app, app.PacejkaOnlyIADropDown.Value);
                    xData = app.LatCamberLUTData.saData{fzIndex, iaIndex};
                    yData = app.LatCamberLUTData.nfyData{fzIndex, iaIndex};
                    if app.RunOptions.CreateFits
                        pacejkaX = app.RunOptions.SASampleVals;
                        %if app.SeparateSA
                        %    pacejkaY = pacejka(app.PacejkaCoeff(:, SAdirIndex, fzIndex, iaIndex, pIndex), deg2rad(pacejkaX));
                        %else
                        %    pacejkaY = pacejka(app.PacejkaCoeff(:, fzIndex, iaIndex, pIndex), deg2rad(pacejkaX));
                        %end
                        pacejkaY = app.LatCamberLUTData.nfySamplePoints(:, fzIndex, iaIndex);
                        app.PacejkaFitAxesHandle = plot(app.PacejkaFitAxes, pacejkaX, pacejkaY, 'red');
                        if app.LatCamberLUTData.nfyExitFlags(fzIndex, iaIndex) == 0
                            app.PacejkaFitNotCompletedLabel.Visible = 'on';
                        else
                            app.PacejkaFitNotCompletedLabel.Visible = 'off';
                        end
                    end
                    
                case CamberOrPressureState.Pressure
                    fzIndex = getFZIndexFromStringPacejka(app, app.PacejkaFZDropDown.Value);
                    pIndex = getPIndexFromStringPacejka(app, app.PacejkaOnlyPDropDown.Value);
                    xData = app.LatPressureLUTData.saData{fzIndex, pIndex};
                    yData = app.LatPressureLUTData.nfyData{fzIndex, pIndex};
                    if app.RunOptions.CreateFits
                        pacejkaX = app.RunOptions.SASampleVals;
                        %if app.SeparateSA
                        %    pacejkaY = pacejka(app.PacejkaCoeff(:, SAdirIndex, fzIndex, iaIndex, pIndex), deg2rad(pacejkaX));
                        %else
                        %    pacejkaY = pacejka(app.PacejkaCoeff(:, fzIndex, iaIndex, pIndex), deg2rad(pacejkaX));
                        %end
                        pacejkaY = app.LatPressureLUTData.nfySamplePoints(:, fzIndex, pIndex);
                        app.PacejkaFitAxesHandle = plot(app.PacejkaFitAxes, pacejkaX, pacejkaY, 'red');
                        app.PacejkaFitNotCompletedLabel.Visible = app.LatPressureLUTData.nfyExitFlags(fzIndex, pIndex) == 0;
                    end
                    
                case CamberOrPressureState.Both
                    fzIndex = getFZIndexFromStringPacejka(app, app.PacejkaFZDropDown.Value);
                    iaIndex = getIAIndexFromStringPacejka(app, app.PacejkaBothIADropDown.Value);
                    pIndex = getPIndexFromStringPacejka(app, app.PacejkaBothPDropDown.Value);
                    
                    xData = app.LatLUTData.saData{fzIndex, iaIndex, pIndex};
                    yData = app.LatLUTData.nfyData{fzIndex, iaIndex, pIndex};
                    if app.RunOptions.CreateFits
                        pacejkaX = app.RunOptions.SASampleVals;
                        pacejkaY = app.LatLUTData.nfySamplePoints(:, fzIndex, iaIndex, pIndex);
                        app.PacejkaFitAxesHandle = plot(app.PacejkaFitAxes, pacejkaX, pacejkaY, 'red');
                        app.PacejkaFitNotCompletedLabel.Visible = app.LatLUTData.nfyExitFlags(fzIndex, iaIndex, pIndex) == 0;
                    end
            end
            
            app.PacejkaDataAxesHandle = scatter(app.PacejkaFitAxes, xData, yData, 5, 'blue');
            axis(app.PacejkaFitAxes, [-app.RunOptions.SABound app.RunOptions.SABound -3.2 3.2]);
        end
        
        function updateLoadSensAxesAuto(app)
            saIndex = find(app.RunOptions.SASampleVals == app.SASlider.Value);
            if isempty(saIndex)
                error(['Matt error: no saIndex found. Slider value: ' num2str(app.SASlider.Value)])
            end
            updateLoadSensAxesGivenSAIndex(app, saIndex);
        end
        
        function updateLoadSensAxesWithRound(app, newVal)
            [~, ind] = min(abs(app.RunOptions.SASampleVals - newVal));
            roundedVal = app.RunOptions.SASampleVals(ind);
            if roundedVal ~= app.SASlider.Value
                updateLoadSensAxesGivenSAIndex(app, ind);
                app.SASlider.Value = roundedVal;
            end
        end
        
        function updateLoadSensAxesGivenSAIndex(app, saIndex)
            
            delete(app.LoadSensAxesDataHandle);
            delete(app.LoadSensAxesFitHandle);
            
            switch app.LoadSensIAorPState
                case CamberOrPressureState.Camber
                    iaIndex = getIAIndexFromStringLoadSens(app, app.FZIADropDown.Value);
            
                    % Plot data points
                    actualLoadVals = app.LatCamberLUTData.meanLoads(:, iaIndex);
                    
                    actualCoeff = app.LatCamberLUTData.nfySamplePoints(saIndex, :, iaIndex);
                    
                    app.LoadSensAxesDataHandle = scatter(app.FZAxes, actualLoadVals, actualCoeff, 'magenta');
                    
                    % Plot fit line
                    xvals = -app.RunOptions.LoadSampleVals;
                    yvals = app.LatCamberLUTData.nfyVals(saIndex, :, iaIndex);
                    
                    app.LoadSensAxesFitHandle = line(app.FZAxes, ...
                        'XData', ...
                        xvals, ...
                        'YData', ...
                        yvals, ...
                        'Color', 'cyan');
                    drawnow
                case CamberOrPressureState.Pressure
                    pIndex = getPIndexFromStringLoadSens(app, app.FZPDropDown.Value);
                    
                    % Plot data points
                    actualLoadVals = app.LatPressureLUTData.meanLoads(:, pIndex);
                    actualCoeff = app.LatPressureLUTData.nfySamplePoints(saIndex, :, pIndex);
                    
                    app.LoadSensAxesDataHandle = scatter(app.FZAxes, actualLoadVals, actualCoeff, 'magenta');
                    
                    % Plot fit line
                    xvals = -app.RunOptions.LoadSampleVals;
                    yvals = app.LatPressureLUTData.nfyVals(saIndex, :, pIndex);
                    
                    app.LoadSensAxesFitHandle = line(app.FZAxes, ...
                        'XData', ...
                        xvals, ...
                        'YData', ...
                        yvals, ...
                        'Color', 'cyan');
                    drawnow
                case CamberOrPressureState.Both
                    iaIndex = getIAIndexFromStringLoadSens(app, app.FZIADropDown.Value);
                    pIndex = getPIndexFromStringLoadSens(app, app.FZPDropDown.Value);
                    
                    % Plot data points
                    actualLoadVals = app.LatLUTData.meanLoads(:, iaIndex, pIndex);
                    actualCoeff = app.LatLUTData.nfySamplePoints(saIndex, :, iaIndex, pIndex);
                    
                    app.LoadSensAxesDataHandle = scatter(app.FZAxes, actualLoadVals, actualCoeff, 'magenta');
                    
                    % Plot fit line
                    xvals = -app.RunOptions.LoadSampleVals;
                    yvals = app.LatLUTData.nfyVals(saIndex, :, iaIndex, pIndex);
                    app.LoadSensAxesFitHandle = line(app.FZAxes, ...
                        'XData', ...
                        xvals, ...
                        'YData', ...
                        yvals, ...
                        'Color', 'cyan');
                    drawnow
            end
        end
        
        function structName = getStructNameFromPacejkaState(app)
            switch app.PacejkaState
                case CamberOrPressureState.Camber
                    structName = 'LatCamberLUTData';
                case CamberOrPressureState.Pressure
                    structName = 'LatPressureLUTData';
                case CamberOrPressureState.Both
                    structName = 'LatLUTData';
            end
        end
        
        function structName = getStructNameFromLoadSensState(app)
            switch app.LoadSensIAorPState
                case CamberOrPressureState.Camber
                    structName = 'LatCamberLUTData';
                case CamberOrPressureState.Pressure
                    structName = 'LatPressureLUTData';
                case CamberOrPressureState.Both
                    structName = 'LatLUTData';
            end
        end
        
        function iaIndex = getIAIndexFromStringPacejka(app, strValue)
            structName = getStructNameFromPacejkaState(app);
            value = str2double(strValue);
            iaIndex = find(app.(structName).iaOptions == value);
        end
        
        function fzIndex = getFZIndexFromStringPacejka(app, strValue)
            structName = getStructNameFromPacejkaState(app);
            value = str2double(strValue);
            fzIndex = find(app.(structName).fzOptions == value);
        end
        
        function pIndex = getPIndexFromStringPacejka(app, strValue)
            structName = getStructNameFromPacejkaState(app);
            value = str2double(strValue);
            pIndex = find(app.(structName).pOptions == value);
        end
        
        function iaIndex = getIAIndexFromStringLoadSens(app, strValue)
            structName = getStructNameFromLoadSensState(app);
            value = str2double(strValue);
            iaIndex = find(app.(structName).iaOptions == value);
        end
        
        function pIndex = getPIndexFromStringLoadSens(app, strValue)
            structName = getStructNameFromLoadSensState(app);
            value = str2double(strValue);
            pIndex = find(app.(structName).pOptions == value);
        end
        
        function updatePacejkaDropdowns(app)
            switch app.PacejkaState
                case CamberOrPressureState.Camber
                    app.PacejkaOnlyIADropDown.Items = string(num2cell(app.LatCamberLUTData.iaOptions));
                    app.PacejkaFZDropDown.Items = string(num2cell(app.LatCamberLUTData.fzOptions));
                case CamberOrPressureState.Pressure
                    app.PacejkaOnlyPDropDown.Items = string(num2cell(app.LatPressureLUTData.pOptions));
                    app.PacejkaFZDropDown.Items = string(num2cell(app.LatPressureLUTData.fzOptions));
                case CamberOrPressureState.Both
                    app.PacejkaBothIADropDown.Items = string(num2cell(app.LatLUTData.iaOptions));
                    app.PacejkaBothPDropDown.Items = string(num2cell(app.LatLUTData.pOptions));
                    app.PacejkaFZDropDown.Items = string(num2cell(app.LatLUTData.fzOptions));
            end
        end
        
        function updatePacejkaStateFromDropdown(app)
            switch app.PacejkaVaryDropDown.Value
                case 'Inc. angle'
                    switchPacejkaState(app, CamberOrPressureState.Camber);
                case 'Pressure'
                    switchPacejkaState(app, CamberOrPressureState.Pressure);
            end
        end
        
        function updateLoadSensStateFromDropdown(app)
            switch app.FZVaryDropDown.Value
                case 'Inc. angle'
                    switchLoadSensState(app, CamberOrPressureState.Camber);
                case 'Pressure'
                    switchLoadSensState(app, CamberOrPressureState.Pressure);
            end
        end
        
        function updateLoadSensDropdowns(app)
            switch app.LoadSensIAorPState
                case CamberOrPressureState.Camber
                    app.FZIADropDown.Items = string(num2cell(app.LatCamberLUTData.iaOptions));
                case CamberOrPressureState.Pressure
                    app.FZPDropDown.Items = string(num2cell(app.LatPressureLUTData.pOptions));
                case CamberOrPressureState.Both
                    app.FZIADropDown.Items = string(num2cell(app.LatLUTData.iaOptions));
                    app.FZPDropDown.Items = string(num2cell(app.LatLUTData.pOptions));
            end
        end
        
        function suggestLoadBound(app)
            tireName = app.TireDropDown.Value;
            maxLoad = max(-app.TireDataMap(tireName).FZ);
            maxRoundedLoad = ceil(maxLoad/100) * 100;
            app.LoadUBEditField.Value = maxRoundedLoad;
        end
        
        function updatePrimaryStatusLabel(app, text)
            app.PrimaryStatusLabel.Text = text;
            app.updateSecondaryStatusLabel('');
        end
        
        function updateSecondaryStatusLabel(app, text)
            app.SecondaryStatusLabel.Text = text;
            app.updateTertiaryStatusLabel('');
        end
        
        function updateTertiaryStatusLabel(app, text)
            app.TertiaryStatusLabel.Text = text;
            app.updateUI();
        end
        
        function [processingResults, upperBound] = processTestSection(app, testSection, startIndex)
            
            % Increment depth counter at start of function
            app.DataProcessingDepth = app.DataProcessingDepth + 1;
            
            disp(['Depth: ' num2str(app.DataProcessingDepth) ', Test: ', testSection.Name])
            
            switch app.DataProcessingDepth
                case 1
                    app.updatePrimaryStatusLabel(testSection.Name)
                case 2
                    app.updateSecondaryStatusLabel(testSection.Name)
                case 3
                    app.updateTertiaryStatusLabel(testSection.Name)
            end
            
            recordSection = ~ismember(testSection.Name, app.TestSectionNames) && testSection.Displayed;
            if recordSection
                app.TestSectionNames{end + 1} = testSection.Name;
                treeNode = uitreenode(app.DataProcessingCurrentNode, 'Text', testSection.Name);
                expand(app.TestSectionTree);
                expand(app.DataProcessingCurrentNode);
                app.updateUI();
                % Save the old node and temporarily set this node to be the current (so children can reference it as the parent)
                oldNode = app.DataProcessingCurrentNode;
                app.DataProcessingCurrentNode = treeNode;
            end
            
            [lowerBounds, upperBounds] = testSection.getBounds(app.UsedTireData, startIndex);
            
            nGroups = testSection.BFinder.getN();
            assert(nGroups > 0, 'Matt error: nGroups less than 1')
            nChildren = length(testSection.Children);
            childrenResults = {};
            
            for i = 1:nGroups
                
                lb_i = lowerBounds(i);
                ub_i = upperBounds(i);
                currentIndex = lb_i;
                
                for j = 1:nChildren
                    
                    child = testSection.Children(j);
                    
                    [childResults, ub] = app.processTestSection(child, currentIndex);
                    currentIndex = ub(end);
                    childrenResults{j}(i) = childResults;
                end
                
            end
            
            if recordSection
                % Reset back to the old node once this section's children have finished
                app.DataProcessingCurrentNode = oldNode;
            end
            
            if ~exist('childrenResults', 'var')
                childrenResults = [];
            end
            processingResults = testSection.processData(app, subsetOfStruct(app.UsedTireData, [lowerBounds(1) upperBounds(end)]), childrenResults, app.RunOptions);
            upperBound = upperBounds(end);
            
            % Now decrement depth counter
            app.DataProcessingDepth = app.DataProcessingDepth - 1;
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PrimaryStatusLabel.Text = 'App started';
            app.PacejkaFitAxes.Visible = false;
            app.FZAxes.Visible = false;
            app.PacejkaFitNotCompletedLabel.Visible = false;
            app.RunOptionsPanel.Enable = 'off';
            
            app.PacejkaFitsPanel.Enable = 'off';
            app.LoadSensitivityPanel.Enable = 'off';
            app.CamberSensitivityPanel.Enable = 'off';
            app.PressureSensitivityPanel.Enable = 'off';
            updateUI(app);
            
            
            %app.SourceCodeLink = uihyperlink(app.TitlePanel);
            %app.SourceCodeLink.URL = 'https://github.com/yafta98/TireAnalyzer';
            
            % Empty map which wil be used to map each set of data to the tire name
            app.TireDataMap = containers.Map('KeyType', 'char', 'ValueType', 'any');
            
            % Load files in tire data directory
            folderContents = what('tire_data\');
            % Only list .mat files
            
            matFiles = natsortfiles(folderContents.mat);
            app.FileNames = matFiles;
            % Number of files
            app.nFiles = length(matFiles);
            
            % Load each tire's name into the array
            % At some point, we can parse the tire info from the string
            app.PrimaryStatusLabel.Text = 'Loading data from files into workspace...';
            updateUI(app);
            app.TireNamesRaw = cell(app.nFiles);
            progressDialog = uiprogressdlg(app.TireAnalyzerUIFigure);
            progressDialog.Message = 'Loading data from files into workspace...';
            pause(1.2)
            updateUI(app);
            for i = 1:app.nFiles
                app.SecondaryStatusLabel.Text = "Loading file " + i + "/" + app.nFiles;
                progressDialog.Value = i / app.nFiles;
                updateUI(app);
                
                filePath = strcat('tire_data/', matFiles{i});
                
                % Load the tire info and data
                tireData = load(filePath);
                
                app.TireDataRaw{i} = tireData;
                
                app.TireInfoRaw{i} = struct('fileName', matFiles{i}, 'tireID', tireData.tireid);
                
            end
            
            progressDialog.close();
            
            app.PrimaryStatusLabel.Text = 'Done loading files';
            app.SecondaryStatusLabel.Text = '';
            
            % Concatenate the data from different files but same tire
            app.PrimaryStatusLabel.Text = 'Concantenating data...';
            
            for i = 1:length(app.TireDataRaw) % Iterate over tire data entries (one for each file)
                tireName = app.TireDataRaw{i}.tireid;
                
                if contains(tireName, '--')
                    tireName = extractBetween(tireName, '', ' --');
                    tireName = tireName{1};
                end
                
                fieldNames = fieldnames(app.TireDataRaw{i});
                
                isNew = ~ismember(tireName, app.TireDataMap.keys);
                
                if isNew
                    dataStruct = struct();
                else
                    originalData = app.TireDataMap(tireName);
                end
                
                newData = app.TireDataRaw{i};
                
                for j = 1:numel(fieldNames) % Iterate over field names
                    fieldNameCell = fieldNames(j);
                    fieldName = fieldNameCell{1};
                    if ~strcmp(fieldNames{j}, 'channel') ...
                            && ~strcmp(fieldNames{j}, 'source') ...
                            && ~strcmp(fieldNames{j}, 'testid') ...
                            && ~strcmp(fieldNames{j}, 'tireid') % Don't concatenate these fields
                    
                        if isNew % If this is the first file for this tire
                            dataStruct.(fieldName) = newData.(fieldName);
                        else % If this is a tire that already came up
                            originalData.(fieldName) = ...
                                [originalData.(fieldName); newData.(fieldName)];
                        end
                    end
                end
                % Less than optimal solution since this has to be checked
                % again... but it's preferred over iterating over field
                % names first and then tires
                if isNew
                    app.TireDataMap(tireName) = dataStruct;
                    app.TireInfoMap(tireName) = app.TireInfoRaw{i};
                    app.TireNamesSorted{end + 1} = tireName;
                else
                    app.TireDataMap(tireName) = originalData;
                end
            end
            
            app.nTires = size(app.TireDataMap, 1);
            app.TireNames = app.TireDataMap.keys; % Not sure if necessary
            
            app.TireDropDown.Items = app.TireNamesSorted;
            
            updateTireDetails(app, app.TireDropDown.Value);
            
            suggestLoadBound(app);
            
            app.LoadingImage.Visible = false;
            app.PrimaryStatusLabel.Text = 'Finished loading data. Ready to run.';
            updateUI(app);
            
            disableDefaultInteractivity(app.PacejkaFitAxes);
            disableDefaultInteractivity(app.FZAxes);
            
            app.PacejkaFitAxes.Toolbar.Visible = false;
            app.FZAxes.Toolbar.Visible = false;
            
            app.RunOptionsPanel.Enable = 'on';
        end

        % Button pushed function: RunButton
        function runButtonPushed(app, event)
            app.PrimaryStatusLabel.Text = 'Separating data...';
            app.LoadingImage.Visible = true;
            updateUI(app);
            % Disable all relevant ui elements while loading
            %app.RunOptionsPanel.Enable = 'off';
            
            app.PacejkaFitsPanel.Enable = 'off';
            app.LoadSensitivityPanel.Enable = 'off';
            app.CamberSensitivityPanel.Enable = 'off';
            app.PressureSensitivityPanel.Enable = 'off';
            
            tireName = app.TireDropDown.Value;
            app.Round = app.getRoundNumber(tireName);
            
            app.UsedTireData = app.TireDataMap(tireName);

            app.PacejkaSAdirDropDown.Enable = 'off';
            app.FZSAdirDropDown.Enable = 'off';
            
            app.RunOptions.SaveData = app.SaveFyandMzdataCheckBox.Value;
            app.RunOptions.UseDiffScalingFactors = app.UsedifferentXandYscalingfactorsCheckBox.Value;
            app.RunOptions.XScalingFactor = app.XscalingfactorEditField.Value;
            if app.UsedifferentXandYscalingfactorsCheckBox.Value
                app.RunOptions.YScalingFactor = app.YscalingfactorEditField.Value;
            else
                app.RunOptions.YScalingFactor = app.RunOptions.XScalingFactor;
            end
            app.RunOptions.CreateFits = app.CreatefitsCheckBox.Value;
            app.RunOptions.SeparateSADir = app.SeparateSACheckBox.Value;
            app.RunOptions.SABound = app.SABoundEditField.Value;
            app.RunOptions.SAStepSize = app.SAStepSizeEditField.Value;
            app.RunOptions.SASampleVals = -app.RunOptions.SABound:app.RunOptions.SAStepSize:app.RunOptions.SABound;
            app.RunOptions.PacejkaMaxIterations = app.PacejkafitmaxiterationsEditField.Value;
            app.RunOptions.PacejkaAlgorithm = app.PacejkaalgorithmDropDown.Value;
            app.RunOptions.LoadStepSize = app.LoadStepSizeEditField.Value;
            app.RunOptions.LoadUpperBound = app.LoadUBEditField.Value;
            app.RunOptions.LoadSampleVals = -app.RunOptions.LoadUpperBound:app.RunOptions.LoadStepSize:0;
            app.RunOptions.LoadSensOrder = app.LoadSensitivityOrderSpinner.Value;
            
            testFormat = [];
            % Find the test format for this round
            testFormats = TestFormatSpec.getTestFormats();
            nRounds = length(testFormats);
            for i = 1:nRounds
%                 nTestSections = length(testFormats(i).Tests);
%                 startIndex = 0;
                
                % If this is the correct round
                if testFormats(i).RoundNum == app.Round
                    testFormat = testFormats(i);
%                     for j = 1:nTestSections
%                         test = testFormats(i).Tests(j);
%                         
%                         app.SecondaryStatusLabel.Text = ['Currently on: ' test.Name];
%                         
%                         app.TertiaryStatusLabel.Text = 'Finding upper bound...';
%                         updateUI(app);
%                         
%                         % Get the UB
%                         ub = test.getUpperBound(app.UsedTireData, startIndex, []);
%                         
%                         app.TertiaryStatusLabel.Text = 'Doing stuff with data...';
%                         updateUI(app);
%                         
%                         % Don't care about return struct bc the top level tests access the app directly
%                         test.doStuffWithData(app, app.UsedTireData, startIndex + 1, ub, [], app.RunOptions);
%                         startIndex = ub;
%                     end
                    break;
                end
            end
            
            if isempty(testFormat)
                error(['Matt error: invalid round: ' app.Round])
            end
            
            % This method of running through the test sections is essentially recursive but doesn't use an actual function for it
            
%             depth = 1; % 1 is top layer
%             testSectionArrays = {testFormat.Tests}; % Initially this cell array only has 1 cell
%             testSectionArrayIndexes = 1; % Index of position in current test array AND all of the ones above it
%             
%             % Cell array of cell arrays of double arrays
%             % 1st dim: layer (depth) of format
%             % 2nd dim: test section array in that layer
%             % Finally double array (lb ub), n rows
%             testSectionArrayBounds = {cell(length(testFormat.tests), 1)};
%             allSectionsCompleted = false;
%             
%             while ~allSectionsCompleted
%                 
%                 % Index of test in current test array
%                 currentTestIndex = testSectionArrayIndexes(depth);
%                 currentArray = testSectionArrays{depth};
%                 
%                 if currentIndex == length(currentArray)
%                     if depth == 1
%                         allSectionsCompleted = true;
%                     else
%                         % Go back up one level
%                         testSectionArrays = testSectionArrays(1:(depth - 1));
%                         testSectionArrayIndexes = testSectionArrayIndexes(1:(depth - 1));
%                         depth = depth - 1;
%                         testSectionArrayIndexes(depth) = testSectionArrayIndexes(depth) + 1;
%                         continue;
%                     end
%                 end
%                 
%                 currentTest = currentArray(currentTestIndex);
%                 
%                 [lb, ub] = currentTest.getBounds(app.UsedTireData, currentDataIndex);
%                 
%                 testSectionArrayBounds{depth}{currentTestIndex}
%                 
%                 
%                 disp(['Depth: ', num2str(depth), ', Index: ' num2str(currentIndex), ', Test: ', currentTest.Name])
%                 
%                 % Update status panel
%                 if depth == 1
%                     app.updatePrimaryStatusLabel(['Test: ' currentTest.Name])
%                 elseif depth == 2
%                     app.updateSecondaryStatusLabel(['Test: ' currentTest.Name])
%                 elseif depth == 3
%                     app.updateSecondaryStatusLabel(['Test: ' currentTest.Name])
%                 end
%                 
%                 if ~isempty(currentTest.Children) % Go into child sections
%                     testSectionArrays{end + 1} = currentTest.Children; %#ok<AGROW> % Add another test section array onto this var
%                     
%                     testSectionArrayIndexes = [testSectionArrayIndexes 1]; %#ok<AGROW> 
%                     depth = depth + 1;
%                     continue;
%                 end
%                 
%                 testSectionArrayIndexes(depth) = testSectionArrayIndexes(depth) + 1; %#ok<AGROW> % Increment current index
%                 
%             end
            app.TestSectionNames = {};
            app.DataProcessingCurrentNode = uitreenode(app.TestSectionTree, 'Text', 'Top level');
            currentIndex = 0;
            for i = 1:length(testFormat.Tests)
                [~, ub] = app.processTestSection(testFormat.Tests(i), currentIndex);
                currentIndex = ub(end);
            end
            
            if isempty(app.UsedTireData)
                error('Matt error: tire not found');
            end
            
            app.PacejkaClickRunLabel.Visible = false;
            app.FZClickRunLabel.Visible = false;
            updateUI(app);
            
            if ~isempty(app.LatLUTData)
                switchPacejkaState(app, CamberOrPressureState.Both);
                switchLoadSensState(app, CamberOrPressureState.Both);
            else
                updatePacejkaStateFromDropdown(app);
                updateLoadSensStateFromDropdown(app);
            end
            
            updatePacejkaDropdowns(app);
            updateLoadSensDropdowns(app);
            
            app.PrimaryStatusLabel.Text = 'Plotting Pacejka fits...';
            app.SecondaryStatusLabel.Text = '';
            app.TertiaryStatusLabel.Text = '';
            updateUI(app);
            
            hold(app.PacejkaFitAxes, "on");
            plot(app.PacejkaFitAxes, [0 0], app.RunOptions.YScalingFactor*[-3.2 3.2], 'black');
            plot(app.PacejkaFitAxes, app.RunOptions.XScalingFactor*[-15 15], [0 0], 'black');
            
            updatePacejkaAxes(app);
            updateLoadSensAxesAuto(app);
            
            app.PacejkaFitAxes.Visible = true;
            app.FZAxes.Visible = true;
            
            %app.PrimaryStatusLabel.Text = 'Plotting load sensitivity...';
            updateUI(app);
            
            if app.PacejkaState == CamberOrPressureState.Both
                nSuccess = sum(ismember([app.LatLUTData.nfyExitFlags app.LatLUTData.mzExitFlags], [1 2 3]), 'all');
                nTotalFits = numel([app.LatLUTData.nfyExitFlags app.LatLUTData.mzExitFlags]);
            else
                nSuccess = sum(ismember([app.LatCamberLUTData.nfyExitFlags app.LatCamberLUTData.mzExitFlags], [1 2 3]), 'all') + ...
                    sum(ismember([app.LatPressureLUTData.nfyExitFlags app.LatPressureLUTData.mzExitFlags], [1 2 3]), 'all');
                nTotalFits = numel([app.LatCamberLUTData.nfyExitFlags app.LatCamberLUTData.mzExitFlags]) + ...
                    numel([app.LatPressureLUTData.nfyExitFlags app.LatPressureLUTData.mzExitFlags]);
            end
            
            app.PrimaryStatusLabel.Text = ['Finished with ' num2str(nSuccess) '/' num2str(nTotalFits) ' successful Pacejka fits'];
            % Re-enable all relevant ui elements
            app.RunOptionsPanel.Enable = 'on';
            app.PacejkaFitsPanel.Enable = 'on';
            app.LoadSensitivityPanel.Enable = 'on';
            app.CamberSensitivityPanel.Enable = 'on';
            app.PressureSensitivityPanel.Enable = 'on';
            app.LoadingImage.Visible = false;
            updateUI(app);
            
            if app.SaveFyandMzdataCheckBox.Value
                saX = deg2rad(app.RunOptions.SASampleVals);
                loadY = app.RunOptions.LoadSampleVals;
                if app.PacejkaState == CamberOrPressureState.Both
                    latCoeff = app.LatLUTData.nfyVals(:, :, 1, 3);
                    aligningTorque = app.LatLUTData.mzVals(:, :, 1, 3);
                else
                    latCoeff = app.LatCamberLUTData.nfyVals(:, :, 1);
                    aligningTorque = app.LatCamberLUTData.mzVals(:, :, 1);
                end
                
                save('lateral_data_lut.mat', 'saX', 'loadY', 'latCoeff', 'aligningTorque');
            end
        end

        % Callback function
        function pacejkaSAdirDropdownValueChanged(app, event)
            updatePacejkaAxes(app);
        end

        % Value changed function: PacejkaFZDropDown
        function pacejkaFZDropdownValueChanged(app, event)
            updatePacejkaAxes(app);
        end

        % Callback function
        function pacejkaIADropdownValueChanged(app, event)
            updatePacejkaAxes(app);
        end

        % Value changed function: PacejkaBothPDropDown
        function pacejkaBothPDropdownValueChanged(app, event)
            disp('Changed P drop down')
            updatePacejkaAxes(app);
        end

        % Selection changed function: ButtonGroup
        function tireSelectionMethodChanged(app, event)
            useDropDown = app.ButtonGroup.SelectedObject == app.SelectfromdropdownButton;
            app.TireDropDown.Enable = useDropDown;
            app.RoundDropDown.Enable = ~useDropDown;
            app.ManufacturerDropDown.Enable = ~useDropDown;
            app.OuterdiameterDropDown.Enable = ~useDropDown;
            app.TirewidthDropDown.Enable = ~useDropDown;
            app.RimouterdiameterDropDown.Enable = ~useDropDown;
        end

        % Value changed function: 
        % UsedifferentXandYscalingfactorsCheckBox
        function useDifferentScalingFactorsChanged(app, event)
            value = app.UsedifferentXandYscalingfactorsCheckBox.Value;
            app.YscalingfactorEditField.Visible = value;
            app.ForcecoefficientYscalingfactorEditFieldLabel.Visible = value;
            if (~value)
                app.ScalingfactorLabel.Text = 'Scaling factor:';
            else
                app.ScalingfactorLabel.Text = 'Slip (X) scaling factor:';
            end
        end

        % Value changed function: CreatefitsCheckBox
        function createFitsChanged(app, event)
            if app.CreatefitsCheckBox.Value
                app.FitOptionsPanel.Enable = 'on';
                app.SaveFyandMzdataCheckBox.Enable = 'on';
            else
                app.FitOptionsPanel.Enable = 'off';
                app.SaveFyandMzdataCheckBox.Enable = 'off';
            end
        end

        % Value changed function: TireDropDown
        function tireDropdownChanged(app, event)
            value = app.TireDropDown.Value;
            updateTireDetails(app, value);
            suggestLoadBound(app);
            
            %unsupportedTires = {''}
        end

        % Value changed function: FZPDropDown
        function loadSensPDropdownChanged(app, event)
            updateLoadSensAxesAuto(app);
        end

        % Value changed function: FZIADropDown
        function loadSensIADropdownChanged(app, event)
            updateLoadSensAxesAuto(app);
        end

        % Callback function
        function loadSensSAdirChanged(app, event)
            updateLoadSensAxesAuto(app);
        end

        % Value changed function: SASlider
        function loadSensSASliderChanged(app, event)
            newVal = event.Value;
            updateLoadSensAxesWithRound(app, newVal);
        end

        % Value changed function: SeparateSACheckBox
        function separateSAValueChanged(app, event)
            value = app.SeparateSACheckBox.Value;
            app.SaveFyandMzdataCheckBox.Value = false;
            app.SaveFyandMzdataCheckBox.Enable = ~value;
        end

        % Value changed function: PacejkaVaryDropDown
        function pacejkaVaryButtonPushed(app, event)
            updatePacejkaStateFromDropdown(app);
            updatePacejkaDropdowns(app);
            updatePacejkaAxes(app);
        end

        % Value changed function: PacejkaBothIADropDown, 
        % PacejkaOnlyIADropDown
        function pacejkaOnlyIADropdownChanged(app, event)
            updatePacejkaAxes(app);
        end

        % Value changed function: PacejkaOnlyPDropDown
        function pacejkaOnlyPDropdownChanged(app, event)
            updatePacejkaAxes(app);
        end

        % Value changed function: FZVaryDropDown
        function loadSensVaryDropdownChanged(app, event)
            updateLoadSensStateFromDropdown(app);
            updateLoadSensDropdowns(app);
            updateLoadSensAxesAuto(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TireAnalyzerUIFigure = uifigure('Visible', 'off');
            app.TireAnalyzerUIFigure.Name = 'Tire Analyzer';
            app.TireAnalyzerUIFigure.Icon = 'RFRlogo.png';
            app.TotalGridLayout = uigridlayout(app.TireAnalyzerUIFigure);
            app.BottomGridLayout = uigridlayout(app.TotalGridLayout);
            app.PlotsGridLayout = uigridlayout(app.BottomGridLayout);
            app.PacejkaFitsPanel = uipanel(app.PlotsGridLayout);
            app.PacejkaFitsPanel.Title = 'Pacejka fits';
            app.GridLayout3 = uigridlayout(app.PacejkaFitsPanel);
            app.PacejkaFitAxes = uiaxes(app.GridLayout3);
            title(app.PacejkaFitAxes, 'Pacejka fit')
            xlabel(app.PacejkaFitAxes, 'Slip angle (deg)')
            ylabel(app.PacejkaFitAxes, 'Lateral coefficient')
            app.PacejkaClickRunLabel = uilabel(app.GridLayout3);
            app.PacejkaClickRunLabel.Text = 'Click Run to view graphs';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Panel_5 = uipanel(app.GridLayout4);
            app.PacejkaVaryDropDownLabel = uilabel(app.Panel_5);
            app.PacejkaVaryDropDownLabel.Text = 'Vary:';
            app.PacejkaVaryDropDown = uidropdown(app.Panel_5);
            app.PacejkaVaryDropDown.Items = {'Inc. angle', 'Pressure'};
            app.PacejkaVaryDropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaVaryButtonPushed, true);
            app.PacejkaVaryDropDown.Value = 'Inc. angle';
            app.Panel_6 = uipanel(app.GridLayout4);
            app.PacejkaBothIADropDown = uidropdown(app.Panel_6);
            app.PacejkaBothIADropDown.Items = {'-'};
            app.PacejkaBothIADropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaOnlyIADropdownChanged, true);
            app.PacejkaBothIADropDown.Visible = 'off';
            app.PacejkaBothIADropDown.Value = '-';
            app.PacejkaBothIADropDownLabel = uilabel(app.Panel_6);
            app.PacejkaBothIADropDownLabel.Visible = 'off';
            app.PacejkaBothIADropDownLabel.Text = 'IA (deg):';
            app.PacejkaOnlyIADropDown = uidropdown(app.Panel_6);
            app.PacejkaOnlyIADropDown.Items = {'-'};
            app.PacejkaOnlyIADropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaOnlyIADropdownChanged, true);
            app.PacejkaOnlyIADropDown.Value = '-';
            app.PacejkaOnlyIADropDownLabel = uilabel(app.Panel_6);
            app.PacejkaOnlyIADropDownLabel.Text = 'IA (deg):';
            app.Panel_7 = uipanel(app.GridLayout4);
            app.PacejkaBothPDropDown = uidropdown(app.Panel_7);
            app.PacejkaBothPDropDown.Items = {'-'};
            app.PacejkaBothPDropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaBothPDropdownValueChanged, true);
            app.PacejkaBothPDropDown.Visible = 'off';
            app.PacejkaBothPDropDown.Value = '-';
            app.PacejkaBothPDropDownLabel = uilabel(app.Panel_7);
            app.PacejkaBothPDropDownLabel.Visible = 'off';
            app.PacejkaBothPDropDownLabel.Text = 'P (psi):';
            app.PacejkaOnlyPDropDown = uidropdown(app.Panel_7);
            app.PacejkaOnlyPDropDown.Items = {'-'};
            app.PacejkaOnlyPDropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaOnlyPDropdownChanged, true);
            app.PacejkaOnlyPDropDown.Value = '-';
            app.PacejkaOnlyPDropDownLabel = uilabel(app.Panel_7);
            app.PacejkaOnlyPDropDownLabel.Text = 'P (psi):';
            app.Panel_8 = uipanel(app.GridLayout4);
            app.PacejkaFZDropDown = uidropdown(app.Panel_8);
            app.PacejkaFZDropDown.Items = {'-'};
            app.PacejkaFZDropDown.ValueChangedFcn = createCallbackFcn(app, @pacejkaFZDropdownValueChanged, true);
            app.PacejkaFZDropDown.Value = '-';
            app.PacejkaFZDropDownLabel = uilabel(app.Panel_8);
            app.PacejkaFZDropDownLabel.Text = 'FZ (N):';
            app.Panel_9 = uipanel(app.GridLayout4);
            app.PacejkaSAdirDropDown = uidropdown(app.Panel_9);
            app.PacejkaSAdirDropDown.Items = {'+', '-'};
            app.PacejkaSAdirDropDown.Enable = 'off';
            app.PacejkaSAdirDropDown.Value = '+';
            app.PacejkaSAdirDropDownLabel = uilabel(app.Panel_9);
            app.PacejkaSAdirDropDownLabel.Enable = 'off';
            app.PacejkaSAdirDropDownLabel.Text = 'SA dir:';
            app.CamberSensitivityPanel = uipanel(app.PlotsGridLayout);
            app.CamberSensitivityPanel.Title = 'Camber sensitivity';
            app.GridLayout9 = uigridlayout(app.CamberSensitivityPanel);
            app.NotimplementedyetLabel = uilabel(app.GridLayout9);
            app.NotimplementedyetLabel.Text = 'Not implemented yet';
            app.PressureSensitivityPanel = uipanel(app.PlotsGridLayout);
            app.PressureSensitivityPanel.Title = 'Pressure sensitivity';
            app.GridLayout10 = uigridlayout(app.PressureSensitivityPanel);
            app.NotimplementedyetLabel_2 = uilabel(app.GridLayout10);
            app.NotimplementedyetLabel_2.Text = 'Not implemented yet';
            app.LoadSensitivityPanel = uipanel(app.PlotsGridLayout);
            app.LoadSensitivityPanel.Title = 'Load sensitivity';
            app.GridLayout2 = uigridlayout(app.LoadSensitivityPanel);
            app.FZAxes = uiaxes(app.GridLayout2);
            title(app.FZAxes, 'Load sensitivity and fit at SA=Xdeg')
            xlabel(app.FZAxes, 'Load (N)')
            ylabel(app.FZAxes, 'Lateral coefficient')
            app.FZAxes.YLim = [-3.2 3.2];
            app.GridLayout2_2 = uigridlayout(app.GridLayout2);
            app.Panel = uipanel(app.GridLayout2_2);
            app.VaryDropDownLabel = uilabel(app.Panel);
            app.VaryDropDownLabel.Text = 'Vary:';
            app.FZVaryDropDown = uidropdown(app.Panel);
            app.FZVaryDropDown.Items = {'Inc. angle', 'Pressure'};
            app.FZVaryDropDown.ValueChangedFcn = createCallbackFcn(app, @loadSensVaryDropdownChanged, true);
            app.FZVaryDropDown.Value = 'Inc. angle';
            app.Panel_2 = uipanel(app.GridLayout2_2);
            app.IAdegDropDown_2Label = uilabel(app.Panel_2);
            app.IAdegDropDown_2Label.Text = 'IA (deg):';
            app.FZIADropDown = uidropdown(app.Panel_2);
            app.FZIADropDown.Items = {'0', '2', '4'};
            app.FZIADropDown.ValueChangedFcn = createCallbackFcn(app, @loadSensIADropdownChanged, true);
            app.FZIADropDown.Value = '0';
            app.Panel_3 = uipanel(app.GridLayout2_2);
            app.PpsiDropDown_2Label = uilabel(app.Panel_3);
            app.PpsiDropDown_2Label.Text = 'P (psi):';
            app.FZPDropDown = uidropdown(app.Panel_3);
            app.FZPDropDown.Items = {'10', '12', '14'};
            app.FZPDropDown.ValueChangedFcn = createCallbackFcn(app, @loadSensPDropdownChanged, true);
            app.FZPDropDown.Value = '10';
            app.Panel_4 = uipanel(app.GridLayout2_2);
            app.SAdirDropDownLabel = uilabel(app.Panel_4);
            app.SAdirDropDownLabel.Enable = 'off';
            app.SAdirDropDownLabel.Text = 'SA dir:';
            app.FZSAdirDropDown = uidropdown(app.Panel_4);
            app.FZSAdirDropDown.Items = {'+', '-'};
            app.FZSAdirDropDown.Enable = 'off';
            app.FZSAdirDropDown.Value = '+';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.LBdegLabel = uilabel(app.GridLayout6);
            app.LBdegLabel.Text = '-15 deg';
            app.SASlider = uislider(app.GridLayout6);
            app.SASlider.Limits = [-15 15];
            app.SASlider.MajorTicks = [-15 0 15];
            app.SASlider.MajorTickLabels = {''};
            app.SASlider.ValueChangedFcn = createCallbackFcn(app, @loadSensSASliderChanged, true);
            app.UBdegLabel = uilabel(app.GridLayout6);
            app.UBdegLabel.Text = '15 deg';
            app.FZClickRunLabel = uilabel(app.GridLayout2);
            app.FZClickRunLabel.Text = 'Click Run to view graphs';
            app.RunOptionsPanel = uipanel(app.BottomGridLayout);
            app.GridLayout8 = uigridlayout(app.RunOptionsPanel);
            app.Panel_21 = uipanel(app.GridLayout8);
            app.CreatefitsCheckBox = uicheckbox(app.Panel_21);
            app.CreatefitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @createFitsChanged, true);
            app.CreatefitsCheckBox.Text = 'Create fits';
            app.CreatefitsCheckBox.Value = true;
            app.Panel_20 = uipanel(app.GridLayout8);
            app.SaveFyandMzdataCheckBox = uicheckbox(app.Panel_20);
            app.SaveFyandMzdataCheckBox.Text = 'Save Fy and Mz data';
            app.Panel_19 = uipanel(app.GridLayout8);
            app.TireDropDown = uidropdown(app.Panel_19);
            app.TireDropDown.Items = {'-'};
            app.TireDropDown.ValueChangedFcn = createCallbackFcn(app, @tireDropdownChanged, true);
            app.TireDropDown.Value = '-';
            app.TireLabel = uilabel(app.Panel_19);
            app.TireLabel.Text = 'Tire:';
            app.Panel_11 = uipanel(app.GridLayout8);
            app.GridLayout7 = uigridlayout(app.Panel_11);
            app.RoundCheckBox = uicheckbox(app.GridLayout7);
            app.RoundCheckBox.Enable = 'off';
            app.RoundCheckBox.Text = '';
            app.ManufacturerCheckBox = uicheckbox(app.GridLayout7);
            app.ManufacturerCheckBox.Enable = 'off';
            app.ManufacturerCheckBox.Text = '';
            app.ODCheckbox = uicheckbox(app.GridLayout7);
            app.ODCheckbox.Enable = 'off';
            app.ODCheckbox.Text = '';
            app.TireWidthCheckBox = uicheckbox(app.GridLayout7);
            app.TireWidthCheckBox.Enable = 'off';
            app.TireWidthCheckBox.Text = '';
            app.RimODCheckBox = uicheckbox(app.GridLayout7);
            app.RimODCheckBox.Enable = 'off';
            app.RimODCheckBox.Text = '';
            app.CompoundCheckBox = uicheckbox(app.GridLayout7);
            app.CompoundCheckBox.Enable = 'off';
            app.CompoundCheckBox.Text = '';
            app.RimWidthCheckBox = uicheckbox(app.GridLayout7);
            app.RimWidthCheckBox.Enable = 'off';
            app.RimWidthCheckBox.Text = '';
            app.Panel_12 = uipanel(app.GridLayout7);
            app.RoundDropDownLabel = uilabel(app.Panel_12);
            app.RoundDropDownLabel.Enable = 'off';
            app.RoundDropDownLabel.Text = 'Round:';
            app.RoundDropDown = uidropdown(app.Panel_12);
            app.RoundDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.RoundDropDown.Enable = 'off';
            app.RoundDropDown.Value = '1';
            app.Panel_13 = uipanel(app.GridLayout7);
            app.ManufacturerDropDownLabel = uilabel(app.Panel_13);
            app.ManufacturerDropDownLabel.Enable = 'off';
            app.ManufacturerDropDownLabel.Text = 'Manufacturer:';
            app.ManufacturerDropDown = uidropdown(app.Panel_13);
            app.ManufacturerDropDown.Items = {'Avon', 'Continental', 'Dunlop', 'Goodyear', 'Hoosier', 'Michelin'};
            app.ManufacturerDropDown.Enable = 'off';
            app.ManufacturerDropDown.Value = 'Avon';
            app.Panel_14 = uipanel(app.GridLayout7);
            app.OuterdiameterDropDownLabel = uilabel(app.Panel_14);
            app.OuterdiameterDropDownLabel.Enable = 'off';
            app.OuterdiameterDropDownLabel.Text = 'Outer diameter:';
            app.OuterdiameterDropDown = uidropdown(app.Panel_14);
            app.OuterdiameterDropDown.Items = {'-'};
            app.OuterdiameterDropDown.Enable = 'off';
            app.OuterdiameterDropDown.Value = '-';
            app.Panel_15 = uipanel(app.GridLayout7);
            app.TirewidthDropDownLabel = uilabel(app.Panel_15);
            app.TirewidthDropDownLabel.Enable = 'off';
            app.TirewidthDropDownLabel.Text = 'Tire width:';
            app.TirewidthDropDown = uidropdown(app.Panel_15);
            app.TirewidthDropDown.Items = {'-'};
            app.TirewidthDropDown.Enable = 'off';
            app.TirewidthDropDown.Value = '-';
            app.Panel_16 = uipanel(app.GridLayout7);
            app.RimouterdiameterDropDownLabel = uilabel(app.Panel_16);
            app.RimouterdiameterDropDownLabel.Enable = 'off';
            app.RimouterdiameterDropDownLabel.Text = 'Rim outer diameter:';
            app.RimouterdiameterDropDown = uidropdown(app.Panel_16);
            app.RimouterdiameterDropDown.Items = {'-'};
            app.RimouterdiameterDropDown.Enable = 'off';
            app.RimouterdiameterDropDown.Value = '-';
            app.Panel_17 = uipanel(app.GridLayout7);
            app.CompoundDropDownLabel = uilabel(app.Panel_17);
            app.CompoundDropDownLabel.Enable = 'off';
            app.CompoundDropDownLabel.Text = 'Compound:';
            app.CompoundDropDown = uidropdown(app.Panel_17);
            app.CompoundDropDown.Items = {'-'};
            app.CompoundDropDown.Enable = 'off';
            app.CompoundDropDown.Value = '-';
            app.Panel_18 = uipanel(app.GridLayout7);
            app.RimwidthDropDownLabel = uilabel(app.Panel_18);
            app.RimwidthDropDownLabel.Enable = 'off';
            app.RimwidthDropDownLabel.Text = 'Rim width:';
            app.RimwidthDropDown = uidropdown(app.Panel_18);
            app.RimwidthDropDown.Items = {'-'};
            app.RimwidthDropDown.Enable = 'off';
            app.RimwidthDropDown.Value = '-';
            app.ButtonGroup = uibuttongroup(app.GridLayout8);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @tireSelectionMethodChanged, true);
            app.SelectfromdropdownButton = uiradiobutton(app.ButtonGroup);
            app.SelectfromdropdownButton.Text = 'Select from dropdown';
            app.SelectfromdropdownButton.Value = true;
            app.SelectindividualparametersButton = uiradiobutton(app.ButtonGroup);
            app.SelectindividualparametersButton.Enable = 'off';
            app.SelectindividualparametersButton.Text = 'Select individual parameters';
            app.FitOptionsPanel = uipanel(app.GridLayout8);
            app.FitOptionsPanel.Title = 'Fit Options';
            app.LoadsensitivityorderLabel = uilabel(app.FitOptionsPanel);
            app.LoadsensitivityorderLabel.Text = 'Load sensitivity order:';
            app.LoadSensitivityOrderSpinner = uispinner(app.FitOptionsPanel);
            app.LoadSensitivityOrderSpinner.Limits = [1 4];
            app.LoadSensitivityOrderSpinner.Tooltip = {'Polynomial order that will be fit to the load sensitivity data.'};
            app.LoadSensitivityOrderSpinner.Value = 2;
            app.SlipanglebounddegLabel = uilabel(app.FitOptionsPanel);
            app.SlipanglebounddegLabel.Text = 'Slip angle bound (deg):';
            app.SABoundEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.SABoundEditField.Value = 15;
            app.SlipanglestepsizedegEditFieldLabel = uilabel(app.FitOptionsPanel);
            app.SlipanglestepsizedegEditFieldLabel.Text = 'Slip angle step size (deg):';
            app.SAStepSizeEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.SAStepSizeEditField.Value = 0.1;
            app.LoadstepsizeNEditFieldLabel = uilabel(app.FitOptionsPanel);
            app.LoadstepsizeNEditFieldLabel.Text = 'Load step size (N):';
            app.LoadStepSizeEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.LoadStepSizeEditField.Value = 20;
            app.LoadupperboundNEditFieldLabel = uilabel(app.FitOptionsPanel);
            app.LoadupperboundNEditFieldLabel.Text = 'Load upper bound (N):';
            app.LoadUBEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.LoadUBEditField.Value = 1200;
            app.PacejkafitmaxiterationsEditFieldLabel = uilabel(app.FitOptionsPanel);
            app.PacejkafitmaxiterationsEditFieldLabel.Text = 'Pacejka fit max iterations:';
            app.PacejkafitmaxiterationsEditField = uieditfield(app.FitOptionsPanel, 'numeric');
            app.PacejkafitmaxiterationsEditField.Value = 3000;
            app.PacejkaalgorithmDropDownLabel = uilabel(app.FitOptionsPanel);
            app.PacejkaalgorithmDropDownLabel.Text = 'Pacejka algorithm:';
            app.PacejkaalgorithmDropDown = uidropdown(app.FitOptionsPanel);
            app.PacejkaalgorithmDropDown.Items = {'trust-region-reflective', 'levenberg-marquardt'};
            app.PacejkaalgorithmDropDown.Value = 'levenberg-marquardt';
            app.SeparateSACheckBox = uicheckbox(app.FitOptionsPanel);
            app.SeparateSACheckBox.ValueChangedFcn = createCallbackFcn(app, @separateSAValueChanged, true);
            app.SeparateSACheckBox.Enable = 'off';
            app.SeparateSACheckBox.Text = 'Separate increasing/decreasing SA';
            app.ScalingfactorsPanel = uipanel(app.GridLayout8);
            app.ScalingfactorsPanel.Title = 'Scaling factors';
            app.UsedifferentXandYscalingfactorsCheckBox = uicheckbox(app.ScalingfactorsPanel);
            app.UsedifferentXandYscalingfactorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @useDifferentScalingFactorsChanged, true);
            app.UsedifferentXandYscalingfactorsCheckBox.Text = 'Use different X and Y scaling factors';
            app.ScalingfactorLabel = uilabel(app.ScalingfactorsPanel);
            app.ScalingfactorLabel.Text = 'Scaling factor:';
            app.XscalingfactorEditField = uieditfield(app.ScalingfactorsPanel, 'numeric');
            app.XscalingfactorEditField.Limits = [0 Inf];
            app.XscalingfactorEditField.Value = 1;
            app.ForcecoefficientYscalingfactorEditFieldLabel = uilabel(app.ScalingfactorsPanel);
            app.ForcecoefficientYscalingfactorEditFieldLabel.Visible = 'off';
            app.ForcecoefficientYscalingfactorEditFieldLabel.Text = 'Force coefficient (Y) scaling factor:';
            app.YscalingfactorEditField = uieditfield(app.ScalingfactorsPanel, 'numeric');
            app.YscalingfactorEditField.Limits = [0 Inf];
            app.YscalingfactorEditField.Visible = 'off';
            app.YscalingfactorEditField.Value = 1;
            app.GridLayout16 = uigridlayout(app.GridLayout8);
            app.RunButton = uibutton(app.GridLayout16, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TireInfoPanel = uipanel(app.BottomGridLayout);
            app.TireInfoPanel.Title = 'Tire Info';
            app.GridLayout14 = uigridlayout(app.TireInfoPanel);
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.TTCRoundLabel = uilabel(app.GridLayout15);
            app.TTCRoundLabel.Text = 'TTC Round';
            app.Label2 = uilabel(app.GridLayout15);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.GridLayout15);
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.GridLayout15);
            app.Label4.Text = 'Label4';
            app.Label5 = uilabel(app.GridLayout15);
            app.Label5.Text = 'Label5';
            app.Label6 = uilabel(app.GridLayout15);
            app.Label6.Text = 'Label6';
            app.Label7 = uilabel(app.GridLayout15);
            app.Label7.Text = 'Label7';
            app.Label8 = uilabel(app.GridLayout15);
            app.Label8.Text = 'Label8';
            app.TestSectionTree = uitree(app.GridLayout14);
            app.ErrorLabel = uilabel(app.GridLayout14);
            app.ErrorLabel.Text = 'Error';
            app.TopGridLayout = uigridlayout(app.TotalGridLayout);
            app.TitlePanel = uipanel(app.TopGridLayout);
            app.GridLayout11 = uigridlayout(app.TitlePanel);
            app.Panel_22 = uipanel(app.GridLayout11);
            app.LoadingImage = uiimage(app.Panel_22);
            app.LoadingImage.ImageSource = 'loading.gif';
            app.Panel_23 = uipanel(app.GridLayout11);
            app.RFRImage = uiimage(app.Panel_23);
            app.RFRImage.ImageSource = 'RFRlogo.png';
            app.TireAnalyzerLabel = uilabel(app.Panel_23);
            app.TireAnalyzerLabel.Text = 'Tire Analyzer';
            app.ByMatthewDrummondStoylesLabel = uilabel(app.Panel_23);
            app.ByMatthewDrummondStoylesLabel.Text = 'By Matthew Drummond-Stoyles';
            app.SeesourcecodeLabel = uilabel(app.Panel_23);
            app.SeesourcecodeLabel.Text = 'See source code:';
            app.PacejkaFitNotCompletedLabel = uilabel(app.Panel_23);
            app.PacejkaFitNotCompletedLabel.Text = 'Fit not completed        ';
            app.Hyperlink = uihyperlink(app.Panel_23);
            app.Hyperlink.URL = 'https://github.com/yafta98/TireAnalyzer';
            app.Hyperlink.Text = 'https://github.com/yafta98/TireAnalyzer';
            app.StatusPanel = uipanel(app.TopGridLayout);
            app.StatusPanel.Title = 'Status';
            app.PrimaryStatusLabel = uilabel(app.StatusPanel);
            app.PrimaryStatusLabel.Text = '';
            app.SecondaryStatusLabel = uilabel(app.StatusPanel);
            app.SecondaryStatusLabel.Text = '';
            app.TertiaryStatusLabel = uilabel(app.StatusPanel);
            app.TertiaryStatusLabel.Text = '';
            % Show the figure after all components are created
            app.TireAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TireAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TireAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TireAnalyzerUIFigure)
        end
    end
end